classdef eVisionAppDeveloper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SCU                           matlab.ui.control.Image
        ofweeksaheadDropDownLabel     matlab.ui.control.Label
        WeeksAhead                    matlab.ui.control.DropDown
        PredictionLevelDropDownLabel  matlab.ui.control.Label
        PredictionLevel               matlab.ui.control.DropDown
        LevelLabel                    matlab.ui.control.Label
        LevelDropDown                 matlab.ui.control.DropDown
        SublevelDropDownLabel         matlab.ui.control.Label
        SublevelDropDown              matlab.ui.control.DropDown
        DiseaseDropDownLabel          matlab.ui.control.Label
        DiseaseDropDown               matlab.ui.control.DropDown
        EpicLab                       matlab.ui.control.Image
        Cepheid                       matlab.ui.control.Image
        BioInnovation                 matlab.ui.control.Image
        GranulateDataLabel            matlab.ui.control.Label
        GranulateCheckBox             matlab.ui.control.CheckBox
        DataAcquisitionDropDownLabel  matlab.ui.control.Label
        DataAcquisitionDropDown       matlab.ui.control.DropDown
        PredictButton                 matlab.ui.control.Button
        DateRangeDropDownLabel        matlab.ui.control.Label
        DateRangeDropDown             matlab.ui.control.DropDown
        StartDateDatePickerLabel      matlab.ui.control.Label
        StartDateDatePicker           matlab.ui.control.DatePicker
        EndDateDatePickerLabel        matlab.ui.control.Label
        EndDateDatePicker             matlab.ui.control.DatePicker
        UploadDataButton              matlab.ui.control.Button
        FilePath                      matlab.ui.control.TextArea
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Download                      matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        AccuracyRateEditFieldLabel    matlab.ui.control.Label
        AccuracyRateEditField         matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        ProgressBar
        Alert
        PredictCounter = 0
    end
    
    methods (Access = private)
        function setting_data(app, codes, names, languages)
            setappdata(0, 'codes', codes); % stores codes of level
            setappdata(0, 'names', names); % stores names of level
            setappdata(0, 'languages', languages); % stores languages of level
        end
        
        function getNationalData(app)
            try
                country_data = readtable('data/national.csv');
            catch
                country_data = readtable('data\national.csv');
            end
            continent_names = country_data.Continent; % extracts names of continents
            country_names = country_data.Name; % country names
            country_codes = country_data.Code; %iso 2 country codes
            country_lang = country_data.PrimaryLanguage;
            count = 1;
            for i = 1:length(country_names)
                if(strcmp(app.LevelDropDown.Value, continent_names(i)))
                    ListOfCountryNames(count) = country_names(i);
                    ListOfCountryCodes(count) = country_codes(i);
                    ListOfCountryLanguages(count) = country_lang(i);
                    count = count + 1;
                end
            end
            setting_data(app, ListOfCountryCodes, ListOfCountryNames, ListOfCountryLanguages)
            ListOfCountryNames = sort(ListOfCountryNames);
            app.SublevelDropDownLabel.Text = 'Select country'; 
            app.SublevelDropDown.Items = [{'--'}, ListOfCountryNames]; % places list of countries to select
            app.SublevelDropDownLabel.Visible = 'on';
            app.SublevelDropDown.Visible = 'on';
        end
        
        function getStateData(app)
            try
                state_data = readtable('data/state.csv');
            catch
                state_data = readtable('data\state.csv');
            end
            state_names = state_data.Name; % state names
            state_codes = state_data.Code; % iso 2 state codes
            state_countries = state_data.CountryName; % country code which the states/sublevels reside
            state_languages = state_data.PrimaryLanguage;
            count = 1;
            for i = 1:length(state_names)
                if(strcmp(app.LevelDropDown.Value, state_countries(i)))
                    ListOfStateNames(count) = state_names(i);
                    ListOfStateCodes(count) = state_codes(i);
                    ListOfStateLanguages(count) = state_languages(i);
                    count = count + 1;
                end
            end
            setting_data(app, ListOfStateCodes, ListOfStateNames, ListOfStateLanguages)
            app.SublevelDropDownLabel.Text = 'Select state';
            app.SublevelDropDown.Items = [{'--'}, ListOfStateNames];
            app.SublevelDropDownLabel.Visible = 'on';
            app.SublevelDropDown.Visible = 'on';
        end
        
        function getMetroData(app)
            app.LevelLabel.Text = sprintf('%s\n%s\n%s', 'Select state in which', 'your metropolitan', 'area resides'); 
            try
               state_data = readcell('data/state.csv'); %extracts names of world's states
            catch
               state_data = readcell('data\state.csv');
            end
            state_names = state_data(3205:3255, 2); 
             % takes in the range that contains the states of the US
             % since we can currently only make predictions using data from 
             % American metropolitan areas
            app.LevelDropDown.Items = [{'--'}, state_names']; %places states in list to choose which state contains metro areas
            app.LevelLabel.Visible = 'on';
            app.LevelDropDown.Visible = 'on';
        end
        
        function getSublevel(app)
             if strcmp(app.PredictionLevel.Value, 'National')  
                try
                    state_data = readtable('data/state.csv');
                catch
                    state_data = readtable('data\state.csv');
                end
                state_names = state_data.Name; % state names
                state_codes = state_data.Code;
                state_countries = state_data.CountryName; % country names which the states/sublevels reside
                count = 1;
                for i = 1:length(state_names)
                    if(strcmp(app.SublevelDropDown.Value, state_countries(i)))
                        ListOfStateNames(count) = state_names(i);
                        ListOfStateCodes(count) = state_codes(i);
                        count = count + 1;
                    end
                end
                setappdata(0, 'subnames', ListOfStateNames); % stores names of level
                setappdata(0, 'subcodes', ListOfStateCodes);
             elseif strcmp(app.PredictionLevel.Value, 'State') && strcmp(app.SublevelDropDown.Value, 'United States of America')   
                app.LevelLabel.Text = sprintf('%s\n%s\n%s', 'Select state in which', 'your metropolitan', 'area resides'); 
                try
                   metro_data = readtable('data/metropolitan.csv'); %extracts names of world's states
                catch
                   metro_data = readtable('data\metropolitan.csv');
                end
                metro_names = metro_data.Name;
                metro_codes = metro_data.Code;
                metro_states = metro_data.State;
                count = 1;
                for i = 1:length(metro_names)
                    if(strcmp(app.SublevelDropDown.Value, metro_states(i)))
                        ListOfMetroNames(count) = metro_names(i);
                        ListOfMetroCodes(count) = metro_codes(i);
                        count = count + 1;
                    end
                end
                setappdata(0, 'subnames', ListOfMetroNames); % stores names of level
                setappdata(0, 'subcodes', ListOfMetroCodes); % stores names of level
             end
        end
        
        function graphData(app, iliRes, iliAct, confidence, error, dates)
            dates = dates(length(dates) - length(iliAct) + 1:length(dates));
            app.ProgressBar.Title = 'Graphing Results';
            app.PredictCounter = app.PredictCounter + 1;
            setappdata(0, 'results', iliRes);
            setappdata(0, 'confidence', confidence);
            plot(app.UIAxes, dates, iliRes)
            hold(app.UIAxes, "on");
            plot(app.UIAxes, dates, iliAct, 'g')
            plot(app.UIAxes, dates, iliRes+confidence, 'r--')
            plot(app.UIAxes, dates, iliRes-confidence, 'r--')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'ILI Cases')
            title(app.UIAxes, [app.DiseaseDropDown.Value, ' Data For ', app.SublevelDropDown.Value])
            legend(app.UIAxes, {'predicted', 'actual', 'confidence intervals'}, 'Location', 'northwest')
%             legend(app.UIAxes, {'predicted', 'confidence intervals'}, 'Location', 'northwest')
            hold(app.UIAxes, "off");
            app.AccuracyRateEditField.Value = (1 - error) * 100;
            if app.AccuracyRateEditField.Value <= 60
                app.Lamp.Color = [1 0 0];
            elseif app.AccuracyRateEditField.Value <= 80
                app.Lamp.Color = [1 1 0];
            else
                app.Lamp.Color = [0 1 0];
            end
%             app.ConfidenceSlider.Visible = 'on';
            app.Lamp.Visible = 'on';
            app.AccuracyRateEditField.Visible = 'on';
            app.AccuracyRateEditFieldLabel.Visible = 'on';
            % plots prediction
            app.Download.Visible = 'on';
        end
        
%         function dt = finddate(app, c1, len)
%             if strcmp(app.PredictionLevel.Value, 'National') && ~strcmp(app.SublevelDropDown.Value, 'United States of America')
%                 data = readtable('FluNetInteractiveReport.csv'); % takes the column of the total influenza cases
%                 cases = data.ALL_INF;
%                 years = data.Year;
%                 weeks = data.Week;
%             else
%                 data = readtable('ILINET.csv');
%                 cases = data.ILITOTAL;
%                 years = data.YEAR;
%                 weeks = data.WEEK;
%             end
%             for i = length(cases):-1:1
%                 if c1 == cases(i)
%                     loc = i;
%                     break
%                 end
%             end
%             week = weeks(loc:length(weeks));
%             year = years(loc:length(weeks));
%             while(1)
%                 if len == length(week)
%                     break
%                 else
%                     week(length(week), :) = [];
%                     year(length(year), :) = [];
%                 end
%             end
%             dt = 1;
%         end
        
        function DownloadData(app)
            results = getappdata(0, 'results');
            confidence = getappdata(0, 'confidence');
            lower = results - confidence;
            upper = results + confidence;
            results(results < 0) = 0;
            lower(lower < 0) = 0;
            upper(upper < 0) = 0;
            data = table(results, upper, lower);
            data.Properties.VariableNames = {'Date', '# of cases', 'Upper Limit', 'Lower Limit'};
            filter = {'*.csv'; '*.xlsx'};
            [file, path] = uiputfile(filter);
            filename = fullfile(path, file);
            writetable(data, filename);
        end
        
        function data = correctData(app, dataMatrix, ILIData)
            if length(ILIData) < length(dataMatrix)
                dataMatrix(length(ILIData) + 1:length(dataMatrix),:) = [];
            elseif length(ILIData) > length(dataMatrix)
                ILIData(length(dataMatrix) + 1:length(ILIData)) = [];
            end
            data = [ILIData dataMatrix];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if app.PredictCounter > 0 
                app.Alert = uiconfirm(app.UIFigure, 'Warning: Doing another prediction will override your previous data', 'Warning', 'Options', {'Continue', 'Stop', 'Do Not Alert Me Again'});
                if ~strcmp(app.Alert, 'Stop') 
                    if strcmp(app.Alert, 'Do Not Alert Me Again')
                        app.PredictCounter = -1;
                    end
                    cla(app.UIAxes, 'reset')
                    app.AccuracyRateEditField.Value = 0;
                    app.Lamp.Visible = 'off';
                    app.AccuracyRateEditField.Visible = 'off';
                    app.AccuracyRateEditFieldLabel.Visible = 'off';
                    app.Download.Visible = 'off';
                end 
            end
            figure = app.UIFigure;
            app.ProgressBar = uiprogressdlg(figure, 'Title', 'Locating Desired Area', ...
                'Message', 'Please Stand By', 'Indeterminate', 'on');
            names = getappdata(0, 'names'); % calls the data of the names of the sublevel areas which were stored earlier 
            codes = getappdata(0, 'codes'); % same as previous w/ the level codes used to distinguish sublevel
            langs = getappdata(0, 'languages');
            for i = 1:length(codes)
                if(strcmp(app.SublevelDropDown.Value, names(i))) % searches for and extracts the code and language of the selected sublevel
                    code = codes(i); 
                    lang = langs(i);
                    break
                end  
            end
            if strcmp(app.DataAcquisitionDropDown.Value, "Automatic Data Scraping")
                app.ProgressBar.Title = 'Scraping Google Trends Data';
                if app.GranulateCheckBox.Value == true
                    if strcmp(app.SublevelDropDown.Value, 'United States of America')
                        [dataMatrix, dates] = trends_scraper_developer(app, {'US-CA', 'US-TX', 'US-NY'}, lang, app.DiseaseDropDown.Value, app.PredictionLevel.Value);
                    else
                        [dataMatrix, dates] = trends_scraper_developer(app, getappdata(0, 'subcodes'), lang, app.DiseaseDropDown.Value, app.PredictionLevel.Value);
                    end
                else
                    [dataMatrix, dates] = trends_scraper_developer(app, code, lang, app.DiseaseDropDown.Value, app.PredictionLevel.Value);
                end
            end
%             dataMatrix.Normal = trends_scraper_developer(code, lang);
%             dataMatrix.Gran = trends_scraper_developer(getappdata(0, 'subcodes'), lang);
%             app.ProgressBar.Title = 'Scraping CDC Data';
%             ILIData = case_data_scraper(app);
%             if isnan(ILIData)
%                 close(app.ProgressBar)
%                 pause
%             end
            if strcmp(app.DataAcquisitionDropDown.Value, "File Upload")
                data = getappdata(0, 'data')
                if strcmp(app.DateRangeDropDown.Value, "Past 5 Years")
                    time = 'today 5-y';
                else
                    time = string(app.StartDateDatePicker.Value) + ' ' + string(app.EndDateDatePicker.Value);
                end
                dates = py.google_scraper.get_date(char(time));
                dates = cellfun(@char, cell(dates), 'UniformOutput', false)';
            elseif strcmp(app.DiseaseDropDown.Value, 'Influenza')
                app.ProgressBar.Title = 'Scraping Case Data';
                [ILIData, dates] = case_data_scraper(app, dates);
                data = correctData(app, dataMatrix, ILIData);
            elseif strcmp(app.DiseaseDropDown.Value, 'COVID-19')
                app.ProgressBar.Title = 'Scraping Case Data';
                [ILIData, dates] = case_data_scraper(app, dates);
                data = correctData(app, dataMatrix, ILIData);
            end
%             data.Gran = correctData(app, dataMatrix.Gran, ILIData);            
            app.ProgressBar.Title = 'Preparing Your Prediction';
            predict_ahead = str2double(app.WeeksAhead.Value);
            if strcmp(app.DiseaseDropDown.Value, 'Influenza')
                [iliRes, iliAct, confidence, error, dates] = ModularInForAcc_World_Function(predict_ahead, data, dates);        
            elseif strcmp(app.DiseaseDropDown.Value, 'COVID-19')
                [iliRes, iliAct, confidence, error, dates] = covid_predictor(predict_ahead, data, dates);
            end
            if app.PredictCounter ~= -1
                app.PredictCounter = app.PredictCounter + 1;
            end
            app.AccuracyRateEditField.Value = double(error);
            graphData(app, iliRes, iliAct, confidence, double(error), dates)
            close(app.ProgressBar)
        end

        % Value changed function: PredictionLevel
        function PredictionLevelValueChanged(app, event)
%             app.SublevelDropDownLabel.Visible = 'off';
%             app.SublevelDropDown.Visible = 'off';
            if strcmp(app.DiseaseDropDown.Value, 'Influenza')
                national = {'--', 'Africa', 'Asia', 'Europe', 'Oceania', 'North America', 'South America'};
            elseif strcmp(app.DiseaseDropDown.Value, 'COVID-19')
                national = {'--', 'Africa', 'Asia', 'Europe', 'Oceania', 'North America', 'South America'};                
            end
            value = app.PredictionLevel.Value; %value is changed to selected level, which is either 'National', 'State', or 'Metropolitan'
            if strcmp(value, 'National')
                app.LevelLabel.Text = sprintf('%s\n%s\n%s', 'Select continent in', 'which your country',  'resides');
                app.LevelDropDown.Items = national;
                app.LevelLabel.Visible = 'on';
                app.LevelDropDown.Visible = 'on';      
            elseif strcmp(value, 'State')  
                 app.LevelLabel.Text = sprintf('%s\n%s\n%s', 'Select country in', 'which your state', 'resides');
                 try
                    country_data = readtable('data/national.csv');
                 catch
                    country_data = readtable('data\national.csv');
                 end
                 country_names = country_data.Name; %extracts names of the world's countries
                 country_names = sort(country_names);
                 app.LevelDropDown.Items = [country_names']; %places countries in list to select from
                 app.LevelLabel.Visible = 'on';
                 app.LevelDropDown.Visible = 'on';
%             elseif strcmp(value, 'Metropolitan')
%                  app.LevelLabel.Text = sprintf('%s\n%s\n%s', 'Select state in which', 'your metropolitan', 'area resides'); 
%                  try
%                     state_data = readcell('data/state.csv'); %extracts names of world's states
%                  catch
%                     state_data = readcell('data\state.csv');
%                  end
%                  state_names = state_data(3204:3254, 2); 
%                  %takes in the range that contains the states of the US
%                  % since we can currently only make predictions using data from 
%                  % American metropolitan areas
%                  app.LevelDropDown.Items = [{'--'}, state_names']; %places states in list to choose which state contains metro areas
%                  app.LevelLabel.Visible = 'on';
%                  app.LevelDropDown.Visible = 'on';
            end
        end

        % Value changed function: LevelDropDown
        function LevelDropDownValueChanged(app, event)
            if strcmp(app.PredictionLevel.Value, 'National')
                getNationalData(app);
            elseif strcmp(app.PredictionLevel.Value, 'State')
                getStateData(app);
            elseif strcmp(app.PredictionLevel.Value, 'Metropolitan')
                getMetroData(app);
            end
        end

        % Value changed function: SublevelDropDown
        function SublevelDropDownValueChanged(app, event)
            getSublevel(app);
%             app.GranulateCheckBox.Visible = 'on';
%             app.GranulateDataLabel.Visible = 'on';
%             app.PredictButton.Visible = 'on';
        end

        % Value changed function: WeeksAhead
        function WeeksAheadValueChanged(app, event)
            app.PredictionLevelDropDownLabel.Visible = 'on';
            app.PredictionLevel.Visible = 'on';
        end

        % Value changed function: DiseaseDropDown
        function DiseaseDropDownValueChanged(app, event)
            if strcmp(app.DiseaseDropDown.Value, 'Influenza')
                app.DateRangeDropDown.Items = {'--', 'Custom', 'Past 5 Years'};       
                app.ofweeksaheadDropDownLabel.Text = '# of weeks ahead';
                app.WeeksAhead.Items = {'3', '7', '14'};
            elseif strcmp(app.DiseaseDropDown.Value, 'COVID-19')
                app.DateRangeDropDown.Items = {'--', 'Custom', 'All Available Dates'};
                app.ofweeksaheadDropDownLabel.Text = '# of weeks ahead';
                app.WeeksAhead.Items = {'1', '2', '3'};
            end
            app.WeeksAhead.Visible = 'on';
            app.ofweeksaheadDropDownLabel.Visible = 'on';
        end

        % Button pushed function: Download
        function DownloadPushed(app, event)
            DownloadData(app)
        end

        % Value changed function: DateRangeDropDown
        function DateRangeDropDownValueChanged(app, event)
%             app.UploadDataButton.Visible = 'off';
%             app.FilePath.Visible = 'off';            
            value = app.DateRangeDropDown.Value;
            if strcmp(value, "Custom")
                app.StartDateDatePicker.Visible = 'on';
                app.StartDateDatePickerLabel.Visible = 'on';
                app.EndDateDatePicker.Visible = 'on';
                app.EndDateDatePickerLabel.Visible = 'on';
            elseif strcmp(value, "Past 5 Years") || strcmp(value, "All Available Dates")
                app.StartDateDatePicker.Visible = 'off';
                app.StartDateDatePickerLabel.Visible = 'off';
                app.EndDateDatePicker.Visible = 'off';
                app.EndDateDatePickerLabel.Visible = 'off';
            end
%             app.GranulateDataLabel.Visible = 'on';
%             app.GranulateCheckBox.Visible = 'on';
            app.PredictButton.Visible = 'on';
        end

        % Value changed function: DataAcquisitionDropDown
        function DataAcquisitionDropDownValueChanged(app, event)
            value = app.DataAcquisitionDropDown.Value;
%             app.GranulateCheckBox.Visible = 'off';
%             app.GranulateDataLabel.Visible = 'off';
            if strcmp(app.DiseaseDropDown.Value, 'Influenza')
                app.DateRangeDropDown.Items = {'--', 'Custom', 'Past 5 Years'}; 
            elseif strcmp(app.DiseaseDropDown.Value, 'COVID-19')
                app.DateRangeDropDown.Items = {'--', 'Custom', 'All Available Dates'};
            end

            if strcmp(value, "File Upload")
                app.StartDateDatePicker.Visible = 'off';
                app.StartDateDatePickerLabel.Visible = 'off';
                app.EndDateDatePicker.Visible = 'off';
                app.EndDateDatePickerLabel.Visible = 'off';
                app.DateRangeDropDown.Visible = 'on';
                app.DateRangeDropDownLabel.Visible = 'on';
                app.UploadDataButton.Visible = 'on';
                app.FilePath.Visible = 'on';                
                app.GranulateCheckBox.Visible = 'off';
                app.GranulateDataLabel.Visible = 'off';             
            elseif strcmp(value, "Automatic Data Scraping")
                app.UploadDataButton.Visible = 'off';
                app.FilePath.Visible = 'off';
                app.DateRangeDropDown.Visible = 'on';
                app.DateRangeDropDownLabel.Visible = 'on';
                app.GranulateCheckBox.Visible = 'on';
                app.GranulateDataLabel.Visible = 'on';
            end
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
%             count = getappdata(0, 'count');
%             name = getappdata(0, 'file_name');
            [file, path] = uigetfile({'*.csv'; '*.xslx'});
            if path == 0
                return
            end
            full_path = append(string(path), string(file))
            data = readtable(full_path);
%             name(count) = file;
%             if ~isempty(app.TextArea.Value)
%                 app.FilePath.Value = name;
%             else
%                 app.FilePath.Value = file;
%             end
            app.FilePath.Value = file;
            setappdata(0, 'data', data);
%             count = count + 1;
%             setappdata(0, 'count', count);
%             setappdata(0, 'file_name', name);
            app.PredictButton.Visible = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {435, 435};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {298, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SCU = uiimage(app.LeftPanel);
            app.SCU.ImageSource = 'scu.png';
            app.ofweeksaheadDropDownLabel = uilabel(app.LeftPanel);
            app.ofweeksaheadDropDownLabel.Text = '# of weeks ahead';
            app.WeeksAhead = uidropdown(app.LeftPanel);
            app.WeeksAhead.Items = {'3', '7', '14'};
            app.WeeksAhead.ValueChangedFcn = createCallbackFcn(app, @WeeksAheadValueChanged, true);
            app.WeeksAhead.Value = '3';
            app.PredictionLevelDropDownLabel = uilabel(app.LeftPanel);
            app.PredictionLevelDropDownLabel.Text = 'Prediction Level';
            app.PredictionLevel = uidropdown(app.LeftPanel);
            app.PredictionLevel.Items = {'--', 'National', 'State'};
            app.PredictionLevel.ValueChangedFcn = createCallbackFcn(app, @PredictionLevelValueChanged, true);
            app.PredictionLevel.Value = '--';
            app.LevelLabel = uilabel(app.LeftPanel);
            app.LevelLabel.Text = 'Level';
            app.LevelDropDown = uidropdown(app.LeftPanel);
            app.LevelDropDown.Items = {'--'};
            app.LevelDropDown.ValueChangedFcn = createCallbackFcn(app, @LevelDropDownValueChanged, true);
            app.LevelDropDown.Value = '--';
            app.SublevelDropDownLabel = uilabel(app.LeftPanel);
            app.SublevelDropDownLabel.Text = 'Sublevel';
            app.SublevelDropDown = uidropdown(app.LeftPanel);
            app.SublevelDropDown.Items = {'--', 'Option 2', 'Option 3', 'Option 4'};
            app.SublevelDropDown.ValueChangedFcn = createCallbackFcn(app, @SublevelDropDownValueChanged, true);
            app.SublevelDropDown.Value = '--';
            app.DiseaseDropDownLabel = uilabel(app.LeftPanel);
            app.DiseaseDropDownLabel.Text = 'Disease';
            app.DiseaseDropDown = uidropdown(app.LeftPanel);
            app.DiseaseDropDown.Items = {'Influenza', 'COVID-19'};
            app.DiseaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DiseaseDropDownValueChanged, true);
            app.DiseaseDropDown.Value = 'Influenza';
            app.EpicLab = uiimage(app.LeftPanel);
            app.EpicLab.ImageSource = 'Epic Lab.png';
            app.Cepheid = uiimage(app.LeftPanel);
            app.Cepheid.ImageSource = 'cepheid.png';
            app.BioInnovation = uiimage(app.LeftPanel);
            app.BioInnovation.ImageSource = 'BioInnovation_Design_Lab_Logo.jpg';
            app.GranulateDataLabel = uilabel(app.LeftPanel);
            app.GranulateDataLabel.Text = 'Granulate Data?';
            app.GranulateCheckBox = uicheckbox(app.LeftPanel);
            app.GranulateCheckBox.Text = '';
            app.DataAcquisitionDropDownLabel = uilabel(app.LeftPanel);
            app.DataAcquisitionDropDownLabel.Text = 'Data Acquisition';
            app.DataAcquisitionDropDown = uidropdown(app.LeftPanel);
            app.DataAcquisitionDropDown.Items = {'--', 'File Upload', 'Automatic Data Scraping'};
            app.DataAcquisitionDropDown.ValueChangedFcn = createCallbackFcn(app, @DataAcquisitionDropDownValueChanged, true);
            app.DataAcquisitionDropDown.Value = '--';
            app.PredictButton = uibutton(app.LeftPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.DateRangeDropDownLabel = uilabel(app.LeftPanel);
            app.DateRangeDropDownLabel.Text = 'Date Range';
            app.DateRangeDropDown = uidropdown(app.LeftPanel);
            app.DateRangeDropDown.Items = {'--', 'Custom', 'Past 5 Years'};
            app.DateRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @DateRangeDropDownValueChanged, true);
            app.DateRangeDropDown.Value = '--';
            app.StartDateDatePickerLabel = uilabel(app.LeftPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.LeftPanel);
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.EndDateDatePickerLabel = uilabel(app.LeftPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.LeftPanel);
            app.EndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.UploadDataButton = uibutton(app.LeftPanel, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.FilePath = uitextarea(app.LeftPanel);
            app.FilePath.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'eVision Forecast')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'ILI Cases')
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.XTickLabel = {'0'; '0.5'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.Download = uibutton(app.RightPanel, 'push');
            app.Download.ButtonPushedFcn = createCallbackFcn(app, @DownloadPushed, true);
            app.Download.Text = 'Download Predicted # of Cases';
            app.Lamp = uilamp(app.RightPanel);
            app.AccuracyRateEditFieldLabel = uilabel(app.RightPanel);
            app.AccuracyRateEditFieldLabel.Text = 'Accuracy Rate (%)';
            app.AccuracyRateEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eVisionAppDeveloper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end